classdef chart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Label       matlab.ui.control.Label
        EditField   matlab.ui.control.EditField
        Button      matlab.ui.control.Button
        Label_2     matlab.ui.control.Label
        DatePicker  matlab.ui.control.DatePicker
        Button_2    matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp % Description
        url2_
    end
    
    methods (Access = private)
        
        function list_date = scrap_title(app, url2, pickday,key)
            try
                all_day = ceil(datenum(datetime('now'))-datenum(pickday)); % all_day:觀察天數
            catch
                all_day = 0;
            end
            url1 = 'https://www.ptt.cc/bbs/';
            url3 = '/index.html';
            url = [url1 url2 url3];
            opts = weboptions('KeyName','Cookie','KeyValue','over18=1'); % 有的看板會先確認是否成年
            if all_day <= 10 * ~isnat(pickday) * ~isempty(key)
                html = string(webread(url,opts));
                % 置底區分隔線"r-list-sep"以下不看
                html = extractBetween(html, '<html>', '<div class="r-list-sep"></div>');
                f = waitbar(0.2,'Please wait...');% 啟動提醒，進度 20%
                
                % 抓出當頁文章的日期list_d
                list_d = extractBetween(html, '<div class="date">', '</div>');
                list_d = flipud(list_d);
                
                list_t = repmat("",length(list_d),1);
                % 抓出當頁文章的標題list_t
                list_t_1 = extractBetween(html, '<div class="title">', '</div>');
                for i = 1:length(list_d)
                    try
                        list_t(i) = extractBetween(list_t_1(i), '">', '</a>');
                    catch
                        list_t(i) = "-";
                    end
                end
                list_t = flipud(list_t);
                
                % 只保留文章標題有關鍵字(key)的文章的"日期"為一個新的list:list_date_1
                list_date_1 = list_d';
                list_date_1 = list_date_1(contains(list_t,key));
                
                list_date = repelem("",70000);
                % list_date是一個陣列 每次遞迴都會放進文章標題有關鍵字(key)的文章的"日期"
                list_date(1:length(list_date_1)) = list_date_1;
                
                waitbar(0.25,f,'熱門的看板可能會需要一些時間');% 提醒進度 25%
                
                strs = strsplit(list_d(end),'/');
                if month(datetime('now')) < str2double(strs(1))
                    list_end = datetime(year(datetime('now'))-1,str2double(strs(1)),str2double(strs(2)));
                else
                    list_end = datetime(year(datetime('now')),str2double(strs(1)),str2double(strs(2)));
                end
                
                % 若table的最後一個date >= 起始日
                while list_end >= pickday
                    a = extractBetween(html, '<a class="btn wide" href="', '">');
                    url = "https://www.ptt.cc" + a(2);
                    opts = weboptions('KeyName','Cookie','KeyValue','over18=1'); % 有的看板會先確認是否成年
                    html = string(webread(url,opts));
                    
                    % 抓出當頁文章的日期list_d
                    list_d = extractBetween(html, '<div class="date">', '</div>');
                    list_d = flipud(list_d);
                    
                    list_t = repmat("",length(list_d),1);
                    % 抓出當頁文章的標題list_t
                    list_t_1 = extractBetween(html, '<div class="title">', '</div>');
                    for i = 1:length(list_d)
                        try
                            list_t(i) = extractBetween(list_t_1(i), '">', '</a>');
                        catch
                            list_t(i) = "-";
                        end
                    end
                    list_t = flipud(list_t);
                    
                    % 只保留文章標題有關鍵字(key)的文章的"日期"為一個新的list:list_date_1
                    list_date_1 = list_d';
                    list_date_1 = list_date_1(contains(list_t,key));
                    
                    plus = length(list_date(list_date ~= "")); % list_date陣列目前的長度
                    list_date(1+plus:length(list_date_1)+plus) = list_date_1;
                    
                    strs = strsplit(list_d(end),'/');
                    if month(datetime('now')) < str2double(strs(1))
                        list_end = datetime(year(datetime('now'))-1,str2double(strs(1)),str2double(strs(2)));
                    else
                        list_end = datetime(year(datetime('now')),str2double(strs(1)),str2double(strs(2)));
                    end
                    
                    if (length(list_date_1)+plus)/1000 > 1
                        bar = 1;
                    else
                        bar = 0.75/1000*(length(list_date_1)+plus) + 0.25;
                    end
                    waitbar(bar,f,'熱門的看板可能會需要一些時間')
                end
                waitbar(1,f,'Finishing');% 最後提醒，進度 100%
                list_date(list_date == "") = [];
                pause(1)
                close(f)
            else
                msgbox('請確認是否有輸入關鍵字及日期，並確認是其是否為10天內。')
                list_date = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, url2)
            app.mainApp = mainapp;
            app.url2_ = url2;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            pickday = app.DatePicker.Value;
            url2 = app.url2_;
            key = app.EditField.Value;
            %爬出來的list_date是所有有包含該關鍵字的文章的日期
            list_date = scrap_title(app, url2, pickday,key);
            
            all_day = ceil(datenum(datetime('now'))-datenum(pickday)); % all_day:觀察天數
            
            tab = tabulate(list_date);
            if ~isempty(list_date)
                times_cell = tab(:,2);
                times_cell = flipud(times_cell); % flipud為反序 改為早的日期先
                date_cell = tab(:,1);
                date_cell = flipud(date_cell); % 改為早的日期先
                
                all_day_time = pickday:datetime('now','Format','dd-MMM-yyyy');
                % table沒有顯示出沒有出現該關鍵字的日子為0次，因此接下來要補上
                date_time = zeros(1,all_day); % date_time:'觀察天數'個0
                for i = 1:all_day
                    for j = 1:length(date_cell)
                        strt = strsplit(date_cell{j},'/');
                        if month(datetime('now')) < str2double(strt(1))
                            datet = datetime(year(datetime('now'))-1,str2double(strt{1}),str2double(strt{2})); % 第j個資料的日期之日期格式
                        else
                            datet = datetime(year(datetime('now')),str2double(strt{1}),str2double(strt{2})); % 第j個資料的日期之日期格式
                        end
                        if datet == all_day_time(i) %if j日 == i日
                            date_time(i) = times_cell{j}; % date_time的第i天改為次數的第j項
                        end
                    end
                end
                
                plot(app.UIAxes,all_day_time,date_time,'--o')
                xlim(app.UIAxes,[pickday datetime('now','Format','dd-MMM-yyyy')])
                ylim(app.UIAxes,[0 max(date_time)+std(date_time)+1])
                xlabel(app.UIAxes,'日期')
                ylabel(app.UIAxes,'出現次數')
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '關鍵字';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '搜尋關鍵字';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '起始日期';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '離開';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '關鍵字出現次數')
            xlabel(app.UIAxes, '日期')
            ylabel(app.UIAxes, '出現次數')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chart(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end