classdef content < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TextArea     matlab.ui.control.TextArea
        TitleLabel   matlab.ui.control.Label
        AuthorLabel  matlab.ui.control.Label
        Button       matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, href)
            app.mainApp = mainapp;
            
            url = "https://www.ptt.cc/"+href;
            opts = weboptions('KeyName','Cookie','KeyValue','over18=1'); % 有的看板會先確認是否成年
            html = string(webread(url,opts));
            expression = ['\d{2}:\d{2}:\d{2} \d{4}</span></div>' ...
                '(?<article>.*)<span class="f2">.*\(ptt\.cc\)'];
            article = regexp(html,expression,'names');
            app.TextArea.Value = article.article;
            
            patterna1 = ['<span class="article-meta-tag">作者</span>' ...
                '<span class="article-meta-value">'];
            patterna2 = '</span></div>';
            author = extractBetween(html, patterna1, patterna2);
            app.AuthorLabel.Text = author;
            
            
            patternt1 = ['<span class="article-meta-tag">標題</span>' ...
                '<span class="article-meta-value">'];
            patternt2 = '</span></div>';
            title = extractBetween(html, patternt1, patternt2);
            app.TitleLabel.Text = title;
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title';
            app.AuthorLabel = uilabel(app.UIFigure);
            app.AuthorLabel.Text = 'Author';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '離開';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = content(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end