classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UITable         matlab.ui.control.Table
        Label_6         matlab.ui.control.Label
        Label_7         matlab.ui.control.Label
        Button          matlab.ui.control.Button
        Label_2         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Label_5         matlab.ui.control.Label
        Button_2        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        Button_3        matlab.ui.control.Button
    end

    
    properties (Access = private)
        main % Description
        url2_
    end
    
    methods (Access = private)
        
        function T = scrap(app, url2)
            
            url1 = 'https://www.ptt.cc/bbs/';
            url3 = '/index.html';
            url = [url1 url2 url3];
            opts = weboptions('KeyName','Cookie','KeyValue','over18=1'); % 有的看板會先確認是否成年
            try
                html = string(webread(url,opts));
                % 置底區分隔線"r-list-sep"以下不看
                html = extractBetween(html, '<html>', '<div class="r-list-sep"></div>');
                
                f = waitbar(0.2,'Please wait...');% 啟動提醒，進度 20%
                
                list_t = extractBetween(html, '<div class="title">', '</div>');
                list_t = flipud(list_t);
                
                list_title = repmat("",10000,1);
                list_href = repmat("",10000,1);
                
                % 標題 網址
                for i = 1:length(list_t)
                    try
                        list_title(i) = extractBetween(list_t(i), '">', '</a>');
                        list_href(i) = extractBetween(list_t(i), '<a href="', '">');
                    catch
                        list_title(i) = "-";
                        list_href(i) = "-";
                    end
                end
                
                % 日期
                list_date = repmat("",10000,1);
                list_date_1 = extractBetween(html, '<div class="date">', '</div>');
                list_date_1 = flipud(list_date_1);
                list_date(1:length(list_date_1)) = list_date_1;
                
                % 作者
                list_author = repmat("",10000,1);
                list_author_1 = extractBetween(html, '<div class="author">', '</div>');
                list_author_1 = flipud(list_author_1);
                list_author(1:length(list_author_1)) = list_author_1;
                
                list_nrec = extractBetween(html, '<div class="nrec">', '</div>');
                list_nrec = flipud(list_nrec);
                list_n = zeros(10000,1);
                % 推數
                for i = 1:length(list_nrec)
                    if contains(list_nrec(i), "爆")
                        list_n(i) = 100;
                    elseif contains(list_nrec(i), "X")
                        list_n(i) = 0;
                    elseif list_nrec(i) == ""
                        list_n(i) = 0;
                    else
                        list_n(i) = extractBetween(list_nrec(i), '">', '</span>');
                    end
                end
                n_count = length(list_nrec);
                
                waitbar(0.4,f,'熱門的看板可能會需要一些時間');% 提醒進度 40%
                % 若table的最後一個date = 第一個date則抓前頁網址 直到不是第一個date
                while list_date(1) == list_date_1(end)
                    a = extractBetween(html, '<a class="btn wide" href="', '">');
                    url = "https://www.ptt.cc" + a(2);
                    opts = weboptions('KeyName','Cookie','KeyValue','over18=1'); % 有的看板會先確認是否成年
                    html = string(webread(url,opts));
                    
                    list_t = extractBetween(html, '<div class="title">', '</div>');
                    list_t = flipud(list_t);
                    plus = length(list_title(list_title ~= ""));
                    
                    % 標題 網址
                    for i = 1:length(list_t)
                        try
                            list_title(i+plus) = extractBetween(list_t(i), '">', '</a>');
                            list_href(i+plus) = extractBetween(list_t(i), '<a href="', '">');
                        catch
                            list_title(i+plus) = "-";
                            list_href(i+plus) = "-";
                        end
                    end
                    
                    % 日期
                    list_date_1 = extractBetween(html, '<div class="date">', '</div>');
                    list_date_1 = flipud(list_date_1);
                    plus = length(list_date(list_date ~= ""));
                    list_date(1+plus:length(list_date_1)+plus) = list_date_1;
                    
                    % 作者
                    list_author_1 = extractBetween(html, '<div class="author">', '</div>');
                    list_author_1 = flipud(list_author_1);
                    plus = length(list_author(list_author ~= ""));
                    list_author(1+plus:length(list_author_1)+plus) = list_author_1;
                    
                    list_nrec = extractBetween(html, '<div class="nrec">', '</div>');
                    list_nrec = flipud(list_nrec);
                    % 推數
                    for i = 1:length(list_nrec)
                        if contains(list_nrec(i), "爆")
                            list_n(i+n_count) = 100;
                        elseif contains(list_nrec(i), "X")
                            list_n(i+n_count) = 0;
                        elseif list_nrec(i) == ""
                            list_n(i+n_count) = 0;
                        else
                            list_n(i+n_count) = extractBetween(list_nrec(i), '">', '</span>');
                        end
                    end
                    n_count = n_count + length(list_nrec);
                    
                end
                waitbar(1,f,'Finishing');% 最後提醒，進度 100%
                pause(1)
                T = table(list_title,list_date,list_author,list_n,list_href);
                Items={'title','date','author','n','href'};
                T.Properties.VariableNames = Items;
                
                timestr = string(datetime('now'));
                t = strsplit(timestr,'-');
                day = t(1);
                if (t(2) == "Jan")
                    mon = " 1";end
                if (t(2) == "Feb")
                    mon = " 2";end
                if (t(2) == "Mar")
                    mon = " 3";end
                if (t(2) == "Apr")
                    mon = " 4";end
                if (t(2) == "May")
                    mon = " 5";end
                if (t(2) == "Jun")
                    mon = " 6";end
                if (t(2) == "Jul")
                    mon = " 7";end
                if (t(2) == "Aug")
                    mon = " 8";end
                if (t(2) == "Sep")
                    mon = " 9";end
                if (t(2) == "Oct")
                    mon = "10";end
                if (t(2) == "Nov")
                    mon = "11";end
                if (t(2) == "Dec")
                    mon = "12";end
                
                T(T.date ~= strcat(mon,"/",day),:) = [];
                close(f)
                app.Button.Enable = 'on';
            catch
                T = [];
                msgbox('無此看板')
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Button.Enable = 'off';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            url2 = app.url2_;
            chart(app,url2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            url2 = app.EditField.Value;
            app.url2_ = url2;
            T = scrap(app, url2);
            app.UITable.Data = T;
            app.UITable.ColumnName = {'標題','日期','作者','推','網址'};
            if height(T) > 0
                % 文章數
                app.Label_6.Text = num2str(size(T,1));
                % 爆文數
                b = 0;
                for i = 1:length(T.n)
                    if T.n(i) == 100
                        b = b+1;
                    end
                end
                app.Label_7.Text = num2str(b);
            end
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            T = app.UITable.Data;
            href = T(indices(1),5);
            href = string(table2cell(href));
            if href ~= "-"
                content(app, href);
            else
                msgbox('文章已刪除')
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            open('pttcrawler功能.pdf')
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '看統計';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '今日文章';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '篇';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '今日爆文';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '篇';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '使用說明';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '離開';
            app.Panel = uipanel(app.UIFigure);
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = '請輸入看板名稱';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '查看今日文章';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end